classdef ContrastChangeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContrastChangeCorridorUIFigure  matlab.ui.Figure
        RoomButtonGroup                 matlab.ui.container.ButtonGroup
        MesoscopeButton                 matlab.ui.control.RadioButton
        TrainingButton                  matlab.ui.control.RadioButton
        Intertrialdistancelabel         matlab.ui.control.Label
        MonitoringPanel                 matlab.ui.container.Panel
        IntertrialdistanceLabel         matlab.ui.control.Label
        TotalElapsedTimeVal             matlab.ui.control.Label
        TotalelapsedtimeLabel           matlab.ui.control.Label
        CurrentTrial                    matlab.ui.control.Label
        TrialLabel                      matlab.ui.control.Label
        IsImageRewarded                 matlab.ui.control.Label
        ImageLabel                      matlab.ui.control.Label
        CurrentRewardml                 matlab.ui.control.Label
        RewardsgivenLabel               matlab.ui.control.Label
        CurrentDistance                 matlab.ui.control.Label
        CurrentpositionLabel            matlab.ui.control.Label
        TrainingPanel                   matlab.ui.container.Panel
        AutomovementCheckBox            matlab.ui.control.CheckBox
        CorridorforwardButton           matlab.ui.control.Button
        AutorewardCheckBox              matlab.ui.control.CheckBox
        ManualRewardButton              matlab.ui.control.Button
        MonitorMQTTPanel                matlab.ui.container.Panel
        SurroundingCheckBox             matlab.ui.control.CheckBox
        MQTTIPDropDown                  matlab.ui.control.DropDown
        MQTTIPLabel                     matlab.ui.control.Label
        LampMonitor                     matlab.ui.control.Lamp
        SetScreenButton                 matlab.ui.control.Button
        ScreenNoEditField               matlab.ui.control.NumericEditField
        ScreenNoEditFieldLabel          matlab.ui.control.Label
        AnimalInformationPanel          matlab.ui.container.Panel
        BlockEditField                  matlab.ui.control.EditField
        BlockEditFieldLabel             matlab.ui.control.Label
        LampID                          matlab.ui.control.Lamp
        SavePathLabel                   matlab.ui.control.Label
        SetIDButton                     matlab.ui.control.Button
        SavedatatoButton                matlab.ui.control.Button
        DateLabelValue                  matlab.ui.control.Label
        DateLabel                       matlab.ui.control.Label
        AnimalIDEditField               matlab.ui.control.EditField
        AnimalIDEditFieldLabel          matlab.ui.control.Label
        ExperimentSetupPanel            matlab.ui.container.Panel
        RewardSizeEditField             matlab.ui.control.NumericEditField
        SizemsLabel                     matlab.ui.control.Label
        RewardLabel                     matlab.ui.control.Label
        LampExperiment                  matlab.ui.control.Lamp
        SetExperimentButton             matlab.ui.control.Button
        HigherEditField                 matlab.ui.control.NumericEditField
        HigherEditFieldLabel            matlab.ui.control.Label
        LowerEditField                  matlab.ui.control.NumericEditField
        LowerEditFieldLabel             matlab.ui.control.Label
        VelocityTresholdsLabel          matlab.ui.control.Label
        ContraststepsEditField          matlab.ui.control.NumericEditField
        ContraststepsEditFieldLabel     matlab.ui.control.Label
        ImagelengthEditField            matlab.ui.control.NumericEditField
        ImagelengthEditFieldLabel       matlab.ui.control.Label
        NoTrialsEditField               matlab.ui.control.NumericEditField
        NoTrialsEditFieldLabel          matlab.ui.control.Label
        TexturesLamp                    matlab.ui.control.Lamp
        SavePathValueLabel              matlab.ui.control.Label
        STOPSAVEButton                  matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        UploadTexturesButton            matlab.ui.control.Button
        TrialDurationplot               matlab.ui.control.UIAxes
        Lickplot                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SaveRoot % Description
        rwrd_img
        Norwrd_img
        trials
        trial
        alpha
        img_len
        lwr_trsh
        hgh_trsh
        distToChange
        cntrst_dx
        rewardsize
        mqttip % Description
        ScreenNo % Description
        stopandsave = 0; % Description
        myMQTT
        distance
        RewardCount = 0;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DateLabelValue.Text = datestr(floor(now), 'yyyy_mm_dd') ;
            Screen('Preference', 'SkipSyncTests', 1);
        end

        % Button pushed function: UploadTexturesButton
        function UploadTexturesButtonPushed(app, event)
            [app.rwrd_img,app.Norwrd_img] = UploadImages();
            app.TexturesLamp.Color = 'g';
        end

        % Button pushed function: SetExperimentButton
        function SetExperimentButtonPushed(app, event)
           [app.trials,app.trial,app.alpha,app.img_len,app.lwr_trsh,app.hgh_trsh,app.distToChange,app.cntrst_dx] = ExperimentSetup(app.NoTrialsEditField.Value,...
                app.ImagelengthEditField.Value,app.ContraststepsEditField.Value,app.LowerEditField.Value,app.HigherEditField.Value);
            %app.Atelabel.Text = num2str(round(aet,2));
            app.rewardsize = app.RewardSizeEditField.Value;
            app.Lickplot.XLim = [0 app.ImagelengthEditField.Value];
            app.Lickplot.YLim = [0 app.NoTrialsEditField.Value];
            app.TrialDurationplot.XLim = [0 app.NoTrialsEditField.Value];
            app.LampExperiment.Color = 'g';
        end

        % Button pushed function: SavedatatoButton
        function SavedatatoButtonPushed(app, event)
            Path = uigetdir('D:\EXP');
            app.SavePathLabel.Text = Path; 
        end

        % Button pushed function: SetIDButton
        function SetIDButtonPushed(app, event)
            app.SaveRoot = fullfile(app.SavePathLabel.Text,strcat(app.AnimalIDEditField.Value,'_',app.DateLabelValue.Text,'_B',app.BlockEditField.Value,'.mat'));
            app.LampID.Color = 'g';
        end

        % Button pushed function: SetScreenButton
        function SetScreenButtonPushed(app, event)
            app.mqttip = app.MQTTIPDropDown.Value;
            app.ScreenNo = app.ScreenNoEditField.Value; 
            app.LampMonitor.Color = 'g';
        end

        % Button pushed function: STOPSAVEButton
        function STOPSAVEButtonPushed(app, event)
            app.stopandsave = 1;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            [screenNumber,~,~,grey] = PsychInit(app.ScreenNo); 
            
            if app.TrainingButton.Value
                offset = 1920;
                res = [2048*3 1540];
            end
            
            [app.rwrd_img,app.Norwrd_img] = ResizeNGreyCentering(app.rwrd_img,app.Norwrd_img,grey,screenNumber,app.SurroundingCheckBox.Value,res);
            
            %% MQTT Sub
            [app.myMQTT,MoveSub,MoveMsgCount,LickSub,LickMsgCount] = MqttSub(app.mqttip);
%             app.myMQTT=mqtt(app.mqttip); %add mqqt server selection
%             LickSub = subscribe(app.myMQTT,'LickPort/','QoS',0);
%             MoveSub = subscribe(app.myMQTT,'sphericalTreadmill/Data','QoS',0); 
%             MoveMsgCount =  0;
%             LickMsgCount =  0; 
            AlreadyGiven = 0;
            Rewardposition = [];          
            %% Experiment 
            
            try
               
               %% Texture making
                PsychImaging('PrepareConfiguration');
                PsychImaging('AddTask', 'General', 'FloatingPoint32BitIfPossible');
                if app.SurroundingCheckBox.Value
                    [win, ~] = PsychImaging('OpenWindow',screenNumber,grey,[offset 0 offset+res(1) res(2)]);
                    % rectsize =[top-left-x top-left-y bottom-right-x bottom-left-y]
                else
                    [win, ~] = PsychImaging('OpenWindow',screenNumber,grey); 
                end
                Screen('ColorRange', win, 1, 0);
                imagtex_reward = Screen('MakeTexture', win, app.rwrd_img , [], [], 1);
                imagtex_non_reward = Screen('MakeTexture', win, app.Norwrd_img , [], [], 1);
               
               %% Random texture selection
               [texture_toshow,Isrewarded] = RandomImgSelection(imagtex_reward,imagtex_non_reward);
               
               %% Experiment init
               [app.distance,SubjM,Licks] = ExperimentInit(app.trial,app.alpha,Isrewarded);
                
               %% Experiment run
                t0 = tic;
                while app.trial<=app.trials
                 
                %% Monitoring
                    
                    app.CurrentRewardml.Text = num2str(app.RewardCount);
                    app.CurrentTrial.Text = num2str(app.trial);
                    
                    if Isrewarded
                        app.IsImageRewarded.Text = 'Rewarded';
                    else
                        app.IsImageRewarded.Text = 'Non Rewarded';
                    end
                    
                    
                %% Autosave each 10 trials
                    
                    if mod(app.trial,10) == 0
                       %[app.myMQTT,LickSub,MoveSub] = Resub(app.myMQTT,app.mqttip);
                       save(app.SaveRoot,"SubjM","Licks","Rewardposition"); 
                    end
                    
                    if app.stopandsave
                        break;
                    end
                    
                   
                %% Modify Texture    
                
                    Screen('Blendfunction', win, GL_SRC_ALPHA, GL_ONE);
                    Screen('DrawTexture', win, texture_toshow, [], [], [], 1, app.alpha);
                    Screen('DrawingFinished', win);
                
                %% Read movement
                    
                    pause(2/1000); 
                    [SubjM,MoveMsgCount] = ReadMov(SubjM,MoveSub,MoveMsgCount,app.distance,app.trial);
                    combinedSpeed = sum(SubjM(end,1:2).^2)^0.5;
                    
                    if app.AutomovementCheckBox.Value
                        combinedSpeed = 0.1;
                    end
                    
                %% Read licks       
                    pause(2/1000);  
                    [Licks, LickMsgCount, IsLicking] = ReadLick(Licks,LickSub,LickMsgCount,app.trial,app.distance,app.alpha,Isrewarded);

                    if IsLicking  
                      scatter(app.Lickplot,Licks(:,2),Licks(:,1),5,"black","filled");
                      drawnow; 
                    end
                    
                %% Change contrast (alpha) and show it to screen
                    if combinedSpeed > app.lwr_trsh && combinedSpeed < app.hgh_trsh % only change contrasts if inside tresholds
                       
                       app.distance = app.distance + combinedSpeed; %distance accumulation
                       app.CurrentDistance.Text = num2str(app.distance,4);
                       steps = app.distance/app.distToChange; % distance traveled is enough to change to next contrast step 
                       
                       if app.distance <= (app.img_len/2)
                           
                           % increase contrast
                            app.alpha = min(1, steps*app.cntrst_dx);               
                       
                       elseif app.distance > (app.img_len/2) && app.distance <= app.img_len
                           
                           % decrease contrast 
                            if app.AutorewardCheckBox.Value && Isrewarded && ~AlreadyGiven
                                [app.RewardCount] = DeliverReward(app.myMQTT,app.RewardCount,app.rewardsize);
                                Rewardposition(app.trial) = app.distance;
                                AlreadyGiven = 1;
                            elseif IsLicking && Isrewarded && ~AlreadyGiven 
                                [app.RewardCount] = DeliverReward(app.myMQTT,app.RewardCount,app.rewardsize);
                                Rewardposition(app.trial) = app.distance;
                                AlreadyGiven = 1;
                            end 
                            app.alpha = max(0, 1-abs(1-steps*app.cntrst_dx));
                       
                       elseif app.distance > app.img_len
                            
                           %Trial Duration
                            TrialDuration(app.trial) = toc(t0);
                            stem(app.TrialDurationplot, TrialDuration) 
                            drawnow;
                            totaltime = cumsum(TrialDuration);
                            app.TotalElapsedTimeVal.Text = num2str(totaltime(end)/60);
                            app.trial = app.trial + 1;
                            
                            %% intertrial distance 
                            InterTrialDistance(MoveSub, MoveMsgCount, app.ImagelengthEditField,app.Intertrialdistancelabel, app.lwr_trsh, app.hgh_trsh);
%                             dist = 0;
%                             while dist<round(app.ImagelengthEditField.Value/3)
%                                 app.Intertrialdistancelabel.Text = num2str(dist,4);
%                                 pause(2/1000);
%                                 if MoveSub.MessageCount > MoveMsgCount
%                                     MoveMsgCount = MoveMsgCount + 1;
%                                     msg = MoveSub.read;
%                                     m = jsondecode(msg{1});
%                                     combSpeed = (m.pitch^2 + m.roll^2)^0.5;
%                                 end
%                                 if combSpeed > app.lwr_trsh && combSpeed < app.hgh_trsh
%                                     dist = dist + combSpeed;
%                                 end
%                             end
                            %%
                            
                            %% Mqtt flush
                            clear app.myMQTT
                            [app.myMQTT,MoveSub,MoveMsgCount,LickSub,LickMsgCount] = MqttSub(app.mqttip);
                            %%
                            
                            %new trial startup 
                            [texture_toshow,Isrewarded] = RandomImgSelection(imagtex_reward,imagtex_non_reward);
                            app.alpha = 0;
                            app.distance = 0;
                            AlreadyGiven = 0;
                            SubjM(end+1,:) = [0 0 0 app.distance app.trial now]; 
                            t0 = tic;
                       end
                       Screen('Flip', win);
                    end
                end
                save(app.SaveRoot,"SubjM","Licks","Rewardposition");
                clear app.myMQTT
                Screen('CloseAll');
                sca;
                return;
            catch
                sca;
                psychrethrow(psychlasterror);
            end
        end

        % Button pushed function: ManualRewardButton
        function ManualRewardButtonPushed(app, event)
            [app.RewardCount] = DeliverReward(app.myMQTT,app.RewardCount,app.rewardsize);
        end

        % Button pushed function: CorridorforwardButton
        function CorridorforwardButtonPushed(app, event)
            app.distance = app.distance + 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContrastChangeCorridorUIFigure = uifigure('Visible', 'off');
            app.ContrastChangeCorridorUIFigure.Name = 'Contrast Change Corridor';
            app.Lickplot = uiaxes(app.ContrastChangeCorridorUIFigure);
            title(app.Lickplot, 'Licks')
            xlabel(app.Lickplot, 'Position')
            ylabel(app.Lickplot, 'Trial #')
            zlabel(app.Lickplot, 'Z')
            app.Lickplot.XLim = [0 1];
            app.TrialDurationplot = uiaxes(app.ContrastChangeCorridorUIFigure);
            title(app.TrialDurationplot, 'Trial Duration')
            xlabel(app.TrialDurationplot, 'Trial #')
            ylabel(app.TrialDurationplot, 'Time (s)')
            zlabel(app.TrialDurationplot, 'Z')
            app.UploadTexturesButton = uibutton(app.ContrastChangeCorridorUIFigure, 'push');
            app.UploadTexturesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTexturesButtonPushed, true);
            app.UploadTexturesButton.Text = '4) Upload Textures';
            app.STARTButton = uibutton(app.ContrastChangeCorridorUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPSAVEButton = uibutton(app.ContrastChangeCorridorUIFigure, 'push');
            app.STOPSAVEButton.ButtonPushedFcn = createCallbackFcn(app, @STOPSAVEButtonPushed, true);
            app.STOPSAVEButton.Text = 'STOP/SAVE';
            app.SavePathValueLabel = uilabel(app.ContrastChangeCorridorUIFigure);
            app.SavePathValueLabel.Text = '';
            app.TexturesLamp = uilamp(app.ContrastChangeCorridorUIFigure);
            app.ExperimentSetupPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.ExperimentSetupPanel.Title = '3) Experiment Setup';
            app.NoTrialsEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.NoTrialsEditFieldLabel.Text = 'No. Trials';
            app.NoTrialsEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.NoTrialsEditField.Value = 200;
            app.ImagelengthEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.ImagelengthEditFieldLabel.Text = 'Image length';
            app.ImagelengthEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.ImagelengthEditField.Value = 15;
            app.ContraststepsEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.ContraststepsEditFieldLabel.Text = 'Contrast steps';
            app.ContraststepsEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.ContraststepsEditField.Value = 15;
            app.VelocityTresholdsLabel = uilabel(app.ExperimentSetupPanel);
            app.VelocityTresholdsLabel.Text = 'Velocity Tresholds';
            app.LowerEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.LowerEditFieldLabel.Text = 'Lower ';
            app.LowerEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.LowerEditField.Value = 0.05;
            app.HigherEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.HigherEditFieldLabel.Text = 'Higher ';
            app.HigherEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.HigherEditField.Value = 2;
            app.SetExperimentButton = uibutton(app.ExperimentSetupPanel, 'push');
            app.SetExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SetExperimentButtonPushed, true);
            app.SetExperimentButton.Text = 'Set';
            app.LampExperiment = uilamp(app.ExperimentSetupPanel);
            app.RewardLabel = uilabel(app.ExperimentSetupPanel);
            app.RewardLabel.Text = 'Reward';
            app.SizemsLabel = uilabel(app.ExperimentSetupPanel);
            app.SizemsLabel.Text = 'Size (ms)';
            app.RewardSizeEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.RewardSizeEditField.Value = 40;
            app.AnimalInformationPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.AnimalInformationPanel.Title = '1) Animal Information';
            app.AnimalIDEditFieldLabel = uilabel(app.AnimalInformationPanel);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.AnimalInformationPanel, 'text');
            app.DateLabel = uilabel(app.AnimalInformationPanel);
            app.DateLabel.Text = 'Date:';
            app.DateLabelValue = uilabel(app.AnimalInformationPanel);
            app.DateLabelValue.Text = '';
            app.SavedatatoButton = uibutton(app.AnimalInformationPanel, 'push');
            app.SavedatatoButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatoButtonPushed, true);
            app.SavedatatoButton.Text = 'Save data to:';
            app.SetIDButton = uibutton(app.AnimalInformationPanel, 'push');
            app.SetIDButton.ButtonPushedFcn = createCallbackFcn(app, @SetIDButtonPushed, true);
            app.SetIDButton.Text = 'Set';
            app.SavePathLabel = uilabel(app.AnimalInformationPanel);
            app.SavePathLabel.Text = '';
            app.LampID = uilamp(app.AnimalInformationPanel);
            app.BlockEditFieldLabel = uilabel(app.AnimalInformationPanel);
            app.BlockEditFieldLabel.Text = 'Block';
            app.BlockEditField = uieditfield(app.AnimalInformationPanel, 'text');
            app.BlockEditField.Value = '1';
            app.MonitorMQTTPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.MonitorMQTTPanel.Title = '2) Monitor & MQTT';
            app.ScreenNoEditFieldLabel = uilabel(app.MonitorMQTTPanel);
            app.ScreenNoEditFieldLabel.Text = 'Screen No.';
            app.ScreenNoEditField = uieditfield(app.MonitorMQTTPanel, 'numeric');
            app.ScreenNoEditField.Value = 3;
            app.SetScreenButton = uibutton(app.MonitorMQTTPanel, 'push');
            app.SetScreenButton.ButtonPushedFcn = createCallbackFcn(app, @SetScreenButtonPushed, true);
            app.SetScreenButton.Text = 'Set';
            app.LampMonitor = uilamp(app.MonitorMQTTPanel);
            app.MQTTIPLabel = uilabel(app.MonitorMQTTPanel);
            app.MQTTIPLabel.Text = 'MQTT IP: ';
            app.MQTTIPDropDown = uidropdown(app.MonitorMQTTPanel);
            app.MQTTIPDropDown.Items = {'tcp://169.254.0.10', 'tcp://127.0.0.1'};
            app.MQTTIPDropDown.Value = 'tcp://169.254.0.10';
            app.SurroundingCheckBox = uicheckbox(app.MonitorMQTTPanel);
            app.SurroundingCheckBox.Text = 'Surrounding';
            app.TrainingPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.TrainingPanel.Title = 'Training';
            app.ManualRewardButton = uibutton(app.TrainingPanel, 'push');
            app.ManualRewardButton.ButtonPushedFcn = createCallbackFcn(app, @ManualRewardButtonPushed, true);
            app.ManualRewardButton.Text = 'Manual Reward';
            app.AutorewardCheckBox = uicheckbox(app.TrainingPanel);
            app.AutorewardCheckBox.Text = 'Auto reward';
            app.CorridorforwardButton = uibutton(app.TrainingPanel, 'push');
            app.CorridorforwardButton.ButtonPushedFcn = createCallbackFcn(app, @CorridorforwardButtonPushed, true);
            app.CorridorforwardButton.Text = 'Corridor forward';
            app.AutomovementCheckBox = uicheckbox(app.TrainingPanel);
            app.AutomovementCheckBox.Text = 'Auto movement';
            app.MonitoringPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.MonitoringPanel.Title = 'Monitoring';
            app.CurrentpositionLabel = uilabel(app.MonitoringPanel);
            app.CurrentpositionLabel.Text = 'Current position:';
            app.CurrentDistance = uilabel(app.MonitoringPanel);
            app.CurrentDistance.Text = {''; ''};
            app.RewardsgivenLabel = uilabel(app.MonitoringPanel);
            app.RewardsgivenLabel.Text = 'Rewards given:';
            app.CurrentRewardml = uilabel(app.MonitoringPanel);
            app.CurrentRewardml.Text = {''; ''};
            app.ImageLabel = uilabel(app.MonitoringPanel);
            app.ImageLabel.Text = 'Image:';
            app.IsImageRewarded = uilabel(app.MonitoringPanel);
            app.IsImageRewarded.Text = {''; ''};
            app.TrialLabel = uilabel(app.MonitoringPanel);
            app.TrialLabel.Text = 'Trial:';
            app.CurrentTrial = uilabel(app.MonitoringPanel);
            app.CurrentTrial.Text = {''; ''};
            app.TotalelapsedtimeLabel = uilabel(app.MonitoringPanel);
            app.TotalelapsedtimeLabel.Text = 'Total elapsed time:';
            app.TotalElapsedTimeVal = uilabel(app.MonitoringPanel);
            app.TotalElapsedTimeVal.Text = {''; ''};
            app.IntertrialdistanceLabel = uilabel(app.MonitoringPanel);
            app.IntertrialdistanceLabel.Text = {'Intertrial distance:'; ''};
            app.Intertrialdistancelabel = uilabel(app.ContrastChangeCorridorUIFigure);
            app.Intertrialdistancelabel.Text = '';
            app.RoomButtonGroup = uibuttongroup(app.ContrastChangeCorridorUIFigure);
            app.RoomButtonGroup.Title = 'Room';
            app.TrainingButton = uiradiobutton(app.RoomButtonGroup);
            app.TrainingButton.Text = 'Training';
            app.TrainingButton.Value = true;
            app.MesoscopeButton = uiradiobutton(app.RoomButtonGroup);
            app.MesoscopeButton.Text = 'Mesoscope';
            % Show the figure after all components are created
            app.ContrastChangeCorridorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContrastChangeGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ContrastChangeCorridorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContrastChangeCorridorUIFigure)
        end
    end
end