classdef WaterCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaterCalibrationAppUIFigure   matlab.ui.Figure
        RewardObjectivemlEditFieldLabel  matlab.ui.control.Label
        RewardObjectivemlEditField    matlab.ui.control.NumericEditField
        CalibrationPanel              matlab.ui.container.Panel
        RepeatsEditFieldLabel         matlab.ui.control.Label
        RepeatsEditField              matlab.ui.control.NumericEditField
        PerformCalibrationButton      matlab.ui.control.Button
        DurationmsEditFieldLabel      matlab.ui.control.Label
        DurationmsEditField           matlab.ui.control.NumericEditField
        CounterEditFieldLabel         matlab.ui.control.Label
        CounterEditField              matlab.ui.control.NumericEditField
        CalibrationMeasurementsPanel  matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        AddMeasurementButton          matlab.ui.control.Button
        CalculateCalibrationCurveButton  matlab.ui.control.Button
        MQTTIPDropDownLabel           matlab.ui.control.Label
        MQTTIPDropDown                matlab.ui.control.DropDown
        NotrialsEditFieldLabel        matlab.ui.control.Label
        NotrialsEditField             matlab.ui.control.NumericEditField
        RewardSizeLabel               matlab.ui.control.Label
        RewardedTrialsEditFieldLabel  matlab.ui.control.Label
        RewardedTrialsEditField       matlab.ui.control.NumericEditField
        ManualuseofthevalvePanel      matlab.ui.container.Panel
        ManualReleaseButton           matlab.ui.control.Button
        TimemsEditFieldLabel          matlab.ui.control.Label
        TimemsEditField               matlab.ui.control.NumericEditField
        AutomaticReleaseButton        matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        calibrationValues = {0,0} % Description
        calibrationCurve % Description
        mqttBroker
        coeff
    end
    
    methods (Access = private)
        
        function Mqttsub(app)
            app.mqttBroker = mqtt(app.MQTTIPDropDown.Value); %add mqqt server selection
        end
        
        function [xData,yData] = calibrateCurve(app)
            if (length(app.calibrationValues)>=4)
                xData = [app.calibrationValues{:,2}]';
                yData = [app.calibrationValues{:,1}]';
                app.calibrationCurve = fit(xData,yData,'poly2');
                app.coeff = fit(xData,yData,'poly2');
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('.\calibration')
            Mqttsub(app)
        end

        % Button pushed function: PerformCalibrationButton
        function PerformCalibrationButtonPushed(app, event)
            for i=1:app.RepeatsEditField.Value
                pause(1);
                publish(app.mqttBroker, 'miniBCS/Valve1/Pulse', num2str(app.DurationmsEditField.Value));
                app.CounterEditField.Value=i;
            end
            app.CounterEditField.Value=0;
        end

        % Button pushed function: AddMeasurementButton
        function AddMeasurementButtonPushed(app, event)
            app.calibrationValues(end+1,:) = {0,0};
            app.UITable.Data = app.calibrationValues;
        end

        % Button pushed function: CalculateCalibrationCurveButton
        function CalculateCalibrationCurveButtonPushed(app, event)
            if (length(app.calibrationValues)>=4)
                [xData,yData] = app.calibrateCurve();
                hFig = figure();
                hAx=axes();
                scatter(xData,yData,'filled'); hold on;
                plot(app.calibrationCurve);
                saveas(hFig,'./calibration/calibration.fig')
                hAx.TickDir = 'out';
                legend off;
                hAx.XLabel.String = app.UITable.ColumnName{2};
                hAx.YLabel.String = app.UITable.ColumnName{1};
                hAx.XLim(1) = 0;hAx.YLim(1) = 0;
                coeff = app.coeff;
            else
                errordlg("Please add atleast 4 calibration measurements");
            end
            um_trial = app.RewardObjectivemlEditField.Value*1000/(app.NotrialsEditField.Value*app.RewardedTrialsEditField.Value);
            save('./calibration/coeff','coeff','um_trial')
            app.RewardSizeLabel.Text = strcat("Reward Size (ms): ",num2str(app.calibrationCurve.p1*(um_trial*um_trial)+app.calibrationCurve.p2*um_trial + app.calibrationCurve.p3,2));
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.calibrationValues(indices(1),indices(2)) = {newData};
        end

        % Button pushed function: ManualReleaseButton
        function ManualReleaseButtonPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Valve1/Pulse', num2str(app.TimemsEditField.Value));
        end

        % Value changed function: AutomaticReleaseButton
        function AutomaticReleaseButtonValueChanged(app, event)
            value = app.AutomaticReleaseButton.Value;
            while value == 1
                pause(1);
                publish(app.mqttBroker, 'miniBCS/Valve1/Pulse', num2str(app.TimemsEditField.Value));
                value = app.AutomaticReleaseButton.Value;
            end
        end

        % Value changed function: MQTTIPDropDown
        function MQTTIPDropDownValueChanged(app, event)
            clear app.mqttBroker
            Mqttsub(app)
        end

        % Close request function: WaterCalibrationAppUIFigure
        function WaterCalibrationAppUIFigureCloseRequest(app, event)
           clear app.mqttBroker
           delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaterCalibrationAppUIFigure = uifigure;
            app.WaterCalibrationAppUIFigure.Name = 'Water Calibration App';
            app.WaterCalibrationAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @WaterCalibrationAppUIFigureCloseRequest, true);
            app.RewardObjectivemlEditFieldLabel = uilabel(app.WaterCalibrationAppUIFigure);
            app.RewardObjectivemlEditFieldLabel.Text = 'Reward Objective (ml)';
            app.RewardObjectivemlEditField = uieditfield(app.WaterCalibrationAppUIFigure, 'numeric');
            app.RewardObjectivemlEditField.Value = 1;
            app.CalibrationPanel = uipanel(app.WaterCalibrationAppUIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.RepeatsEditFieldLabel = uilabel(app.CalibrationPanel);
            app.RepeatsEditFieldLabel.Text = 'Repeats';
            app.RepeatsEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.RepeatsEditField.Value = 50;
            app.PerformCalibrationButton = uibutton(app.CalibrationPanel, 'push');
            app.PerformCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformCalibrationButtonPushed, true);
            app.PerformCalibrationButton.Text = 'Perform Calibration';
            app.DurationmsEditFieldLabel = uilabel(app.CalibrationPanel);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.DurationmsEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.CounterEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CounterEditFieldLabel.Text = 'Counter';
            app.CounterEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.CounterEditField.Editable = 'off';
            app.CalibrationMeasurementsPanel = uipanel(app.WaterCalibrationAppUIFigure);
            app.CalibrationMeasurementsPanel.Title = 'Calibration Measurements';
            app.UITable = uitable(app.CalibrationMeasurementsPanel);
            app.UITable.ColumnName = {'Duration (ms)'; 'Avg. Volume (ul)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.AddMeasurementButton = uibutton(app.CalibrationMeasurementsPanel, 'push');
            app.AddMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @AddMeasurementButtonPushed, true);
            app.AddMeasurementButton.Text = 'Add Measurement';
            app.CalculateCalibrationCurveButton = uibutton(app.CalibrationMeasurementsPanel, 'push');
            app.CalculateCalibrationCurveButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCalibrationCurveButtonPushed, true);
            app.CalculateCalibrationCurveButton.Text = 'Calculate Calibration Curve';
            app.MQTTIPDropDownLabel = uilabel(app.WaterCalibrationAppUIFigure);
            app.MQTTIPDropDownLabel.Text = 'MQTT IP: ';
            app.MQTTIPDropDown = uidropdown(app.WaterCalibrationAppUIFigure);
            app.MQTTIPDropDown.Items = {'tcp://169.254.0.10', 'tcp://127.0.0.1'};
            app.MQTTIPDropDown.ValueChangedFcn = createCallbackFcn(app, @MQTTIPDropDownValueChanged, true);
            app.MQTTIPDropDown.Value = 'tcp://169.254.0.10';
            app.NotrialsEditFieldLabel = uilabel(app.WaterCalibrationAppUIFigure);
            app.NotrialsEditFieldLabel.Text = 'No. trials';
            app.NotrialsEditField = uieditfield(app.WaterCalibrationAppUIFigure, 'numeric');
            app.RewardSizeLabel = uilabel(app.WaterCalibrationAppUIFigure);
            app.RewardSizeLabel.Text = '';
            app.RewardedTrialsEditFieldLabel = uilabel(app.WaterCalibrationAppUIFigure);
            app.RewardedTrialsEditFieldLabel.Text = 'Rewarded Trials (%)';
            app.RewardedTrialsEditField = uieditfield(app.WaterCalibrationAppUIFigure, 'numeric');
            app.RewardedTrialsEditField.Value = 0.5;
            app.ManualuseofthevalvePanel = uipanel(app.WaterCalibrationAppUIFigure);
            app.ManualuseofthevalvePanel.Title = 'Manual use of the valve ';
            app.ManualReleaseButton = uibutton(app.ManualuseofthevalvePanel, 'push');
            app.ManualReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @ManualReleaseButtonPushed, true);
            app.ManualReleaseButton.Text = 'Manual Release';
            app.TimemsEditFieldLabel = uilabel(app.ManualuseofthevalvePanel);
            app.TimemsEditFieldLabel.Text = 'Time (ms)';
            app.TimemsEditField = uieditfield(app.ManualuseofthevalvePanel, 'numeric');
            app.AutomaticReleaseButton = uibutton(app.ManualuseofthevalvePanel, 'state');
            app.AutomaticReleaseButton.ValueChangedFcn = createCallbackFcn(app, @AutomaticReleaseButtonValueChanged, true);
            app.AutomaticReleaseButton.Text = 'Automatic Release';
        end
    end

    methods (Access = public)

        % Construct app
        function app = WaterCalibration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaterCalibrationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaterCalibrationAppUIFigure)
        end
    end
end