classdef MQTTGimblInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MQTTGimblInterfaceUIFigure    matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ControlTab                    matlab.ui.container.Tab
        RangeminsEditField            matlab.ui.control.NumericEditField
        RangeminsEditFieldLabel       matlab.ui.control.Label
        TotalRewardsnEditField        matlab.ui.control.NumericEditField
        TotalRewardsnEditFieldLabel   matlab.ui.control.Label
        TotalWaterulEditField         matlab.ui.control.NumericEditField
        TotalWaterulEditFieldLabel    matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        WaterRewardPanel              matlab.ui.container.Panel
        AutoRewardButton              matlab.ui.control.StateButton
        secondsLabel                  matlab.ui.control.Label
        VariabilityEditField          matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        IntervalEditField             matlab.ui.control.NumericEditField
        EveryEditFieldLabel           matlab.ui.control.Label
        ForcesStopCheckBox            matlab.ui.control.CheckBox
        MaxRewardulEditField          matlab.ui.control.NumericEditField
        MaxRewardulEditFieldLabel     matlab.ui.control.Label
        OpenValveButton               matlab.ui.control.StateButton
        RewardulEditField             matlab.ui.control.NumericEditField
        RewardulEditFieldLabel        matlab.ui.control.Label
        RewardButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        SessionTab                    matlab.ui.container.Tab
        TransferFilesButton           matlab.ui.control.Button
        StopSessionButton             matlab.ui.control.Button
        ResponsesPanel                matlab.ui.container.Panel
        CurrentLabel                  matlab.ui.control.Label
        ScanImageLamp                 matlab.ui.control.Lamp
        ScanImageLampLabel            matlab.ui.control.Label
        BonsaiLamp                    matlab.ui.control.Lamp
        BonsaiLampLabel               matlab.ui.control.Label
        GimblLamp                     matlab.ui.control.Lamp
        GimblLampLabel                matlab.ui.control.Label
        StartSessionButton            matlab.ui.control.Button
        FileFolderLocationsPanel      matlab.ui.container.Panel
        CompleteScanImageEditField    matlab.ui.control.EditField
        CompleteScanImageEditFieldLabel  matlab.ui.control.Label
        CompleteEditField             matlab.ui.control.EditField
        CompleteEditFieldLabel        matlab.ui.control.Label
        NoteFolderlocationsneedtobeaccessiblebylocalcomputersLabel  matlab.ui.control.Label
        ScanImageEditField            matlab.ui.control.EditField
        ScanImageEditFieldLabel       matlab.ui.control.Label
        BonsaiVideoEditField          matlab.ui.control.EditField
        BonsaiVideoEditFieldLabel     matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        GimblLogEditField             matlab.ui.control.EditField
        GimblLogEditFieldLabel        matlab.ui.control.Label
        CalibrationTab                matlab.ui.container.Tab
        CalibrationMeasurementsPanel  matlab.ui.container.Panel
        CalculateCalibrationCurveButton  matlab.ui.control.Button
        RemoveMeasurementButton       matlab.ui.control.Button
        AddMeasurementButton          matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        CalibrationPanel              matlab.ui.container.Panel
        CounterEditField              matlab.ui.control.NumericEditField
        CounterEditFieldLabel         matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        PerformCalibrationButton      matlab.ui.control.Button
        RepeatEditField               matlab.ui.control.NumericEditField
        RepeatEditFieldLabel          matlab.ui.control.Label
        CamerasTab                    matlab.ui.container.Tab
        Camera3SettingsPanel          matlab.ui.container.Panel
        StopButton_3                  matlab.ui.control.Button
        StartButton_3                 matlab.ui.control.Button
        FrameRatehzEditField_3        matlab.ui.control.NumericEditField
        FrameRatehzEditField_3Label   matlab.ui.control.Label
        TriggeronStartCheckBox_3      matlab.ui.control.CheckBox
        Camera2SettingsPanel          matlab.ui.container.Panel
        StopButton_2                  matlab.ui.control.Button
        StartButton_2                 matlab.ui.control.Button
        FrameRatehzEditField_2        matlab.ui.control.NumericEditField
        FrameRatehzEditField_2Label   matlab.ui.control.Label
        TriggeronStartCheckBox_2      matlab.ui.control.CheckBox
        Camera1SettingsPanel          matlab.ui.container.Panel
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        FrameRatehzEditField          matlab.ui.control.NumericEditField
        FrameRatehzEditFieldLabel     matlab.ui.control.Label
        TriggeronStartCheckBox        matlab.ui.control.CheckBox
        SoundTab                      matlab.ui.container.Tab
        IdleNoisePanel                matlab.ui.container.Panel
        PlayButton                    matlab.ui.control.Button
        DurationsecEditField_2        matlab.ui.control.NumericEditField
        DurationsecEditField_2Label   matlab.ui.control.Label
        EnableCheckBox_2              matlab.ui.control.CheckBox
        SoundtypeDropDown             matlab.ui.control.DropDown
        SoundtypeDropDownLabel        matlab.ui.control.Label
        RewardSoundPanel              matlab.ui.container.Panel
        PlayButton_2                  matlab.ui.control.Button
        DurationsecEditField          matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel     matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        EnableCheckBox                matlab.ui.control.CheckBox
        BallTab                       matlab.ui.container.Tab
        AirPushersPanel               matlab.ui.container.Panel
        AllPanel                      matlab.ui.container.Panel
        OffButtonAll                  matlab.ui.control.Button
        OnButtonAll                   matlab.ui.control.Button
        FrontPanel                    matlab.ui.container.Panel
        OffButtonFront                matlab.ui.control.Button
        OnButtonFront                 matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        OffButtonRight                matlab.ui.control.Button
        OnButtonRight                 matlab.ui.control.Button
        LeftPanel                     matlab.ui.container.Panel
        OffButtonLeft                 matlab.ui.control.Button
        OnButtonLeft                  matlab.ui.control.Button
        LockBallServoControlPanel     matlab.ui.container.Panel
        UnlockButton                  matlab.ui.control.Button
        LockButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        rewardTopic = "Gimbl/Reward/";
        lickTopic = "LickPort/";
        startTopic = "Gimbl/Session/Start"; % Receives start/stop
        stopTopic =  "Gimbl/Session/Stop";
        idleTopic = "Gimbl/Idle/"
        
        rewardSub;

        idleSub;
        lickSub;
        
        % Auto reward.
        autoTimer;
        
        % Calibration.
        calibrationValues;
        selectedCalCell;
        calibrationCurve;
        
        % event plotting.
        hReward;
        hLick;
        
        % sound variables
        tone;
        
        
    end
    properties (Access = public)
        mqttBroker;
        % session variables.
        session;
        external;
    end
    
    methods (Access = private)
        
        
        %% Send reward signal over serial.
        function results = waterReward(app,amount,type)
            %% Go from amount to duration.
            if (isempty(app.calibrationCurve))
                errordlg("Please calculate calibration curve");
                return;
            else
                duration = app.calibrationCurve(amount);
                if duration<0, duration=0.01; end 
            end
            if app.TotalWaterulEditField.Value<app.MaxRewardulEditField.Value
                publish(app.mqttBroker, 'miniBCS/Valve1/Pulse', num2str(duration));
                % Play PC sound.
                withSound = "false";
                if (app.EnableCheckBox.Value)
                    withSound = "true";
                    app.playRewardSound();
                end
                % log.
                publish(app.mqttBroker, 'Log/', sprintf('{"source":"matlab","msg":{"action":"Reward Delivery","type":"%s","amount": %.2f,"valveTime":%.2f,"withSound":%s, "frequency":%.2f, "duration":%.2f}}',...
                    type,amount,duration,withSound,app.FrequencyEditField.Value,app.DurationsecEditField.Value));
                % Signal dashboard.
                publish(app.mqttBroker, 'Dash/Water/', sprintf('{"type":"%s","amount": %.2f,"valveTime":%.2f,"withSound":%s, "frequency":%.2f, "duration":%.2f}',...
                    type,amount,duration,withSound,app.FrequencyEditField.Value,app.DurationsecEditField.Value));                
                % Update counters.
                app.TotalRewardsnEditField.Value = app.TotalRewardsnEditField.Value +1;
                app.TotalWaterulEditField.Value = app.TotalWaterulEditField.Value + amount;
                % update plot.
                if (isnan(app.hReward.YData(1)))
                    app.hReward.XData = datetime;
                    app.hReward.YData = 2;
                else
                    app.hReward.XData = [app.hReward.XData,datetime];
                    app.hReward.YData = [app.hReward.YData,2];
                end
            else
                fprintf('\nMax reward limit reached');
                if app.ForcesStopCheckBox.Value, publish(app.mqttBroker, 'Gimbl/Session/ForceStop', '{}'); end
            end

        end
    
        function rewardMsg(app,topic,msg)
            app.waterReward(app.RewardulEditField.Value,'task');
        end
        
        function clearPlot(app)
            app.hReward.XData = datetime;
            app.hReward.YData = NaN;
            app.hLick.XData = datetime;
            app.hLick.YData = NaN;
        end
        
        %% Calculate calibration curve.
        function [xData,yData] = calibrateCurve(app)
            if (length(app.calibrationValues)>=4)
                xData = [app.calibrationValues{:,2}]';
                yData = [app.calibrationValues{:,1}]';
                app.calibrationCurve = fit(xData,yData,'poly2');
            end
        end
        
        %% Update graphics limits in response to new data.
        function graphLimsUpdate(app,source,event)
            maxX = max([max(app.hReward.XData),max(app.hLick.XData)]) + seconds(5);
            minX = maxX - minutes(app.RangeminsEditField.Value);
            app.UIAxes.XLim = [minX,maxX];
        end
        
        
        function result = InitGetPref(app,group,pref,value)
            try 
                result = getpref(group,pref);
                catch
                addpref(group,pref,value);
                result=value;
            end   
        end
        
        % Generate sound.
        function results = genSoundData(app)
            amp=0.5;
            fs=44100;  % sampling frequency
            values=0:1/fs:app.DurationsecEditField.Value;
            app.tone=amp*sin(2*pi* app.FrequencyEditField.Value*values);
        end
        
        function results = playWhiteNoise(app)
            % Generate sound.
            sFreq = 44100;
            y=randn(1,sFreq * app.DurationsecEditField.Value);
            sound(y,sFreq);
        end
        
        function results = idleCallback(app, topic, msg)
            app.idleSound("task");
        end
        
        function results = lickCallback(app,topic,msg)
            % update plot.
            if (isnan(app.hLick.YData(1)))
                app.hLick.XData = datetime;
                app.hLick.YData = 1;
            else
                app.hLick.XData = [app.hLick.XData,datetime];
                app.hLick.YData = [app.hLick.YData,1];
            end
        end
        
        function results = playRewardSound(app)
            sound(app.tone,44100);

        end
        
        function results = idleSound(app,type)
            if (app.EnableCheckBox_2.Value)
                publish(app.mqttBroker, 'Log/', sprintf('{"source":"matlab","msg":{"action":"Idle Sound","type":"%s","sound": "%s","duration":%.2f}}',...
                    type,app.SoundtypeDropDown.Value,app.DurationsecEditField_2.Value));
                switch (app.SoundtypeDropDown.Value)
                    case 'White Noise'
                        app.playWhiteNoise();
                end 
            end
        end
        
        function results = startAutoTimer(app,varargin)
            % generate next interval.
            delay = 0;
            while (delay<=0)
                a = -app.VariabilityEditField.Value;
                b = app.VariabilityEditField.Value;
                r = (b-a).*rand(1,1) + a;
                delay = app.IntervalEditField.Value + round(r,3);
            end
            app.autoTimer = timer("ExecutionMode","singleShot","Name","AutoRewardTimer","TimerFcn",@app.autoTimeFnc,"StartDelay",delay);
            app.autoTimer.start();
        end
        
        function results = autoTimeFnc(app,varargin)
            app.closeTimers();
            if (app.AutoRewardButton.Value)
                app.waterReward(app.RewardulEditField.Value,"manual");
                % start new timer.
                if app.TotalWaterulEditField.Value<app.MaxRewardulEditField.Value
                    app.startAutoTimer();
                else
                    app.AutoRewardButton.Value=false;
                end
            end
        end
        
        function results = closeTimers(app)
            % delete timer.
            timer = timerfind("Name","AutoRewardTimer");
            if (~isempty(timer))
                for i =1:length(timer)
                    stop(timer(i));
                    delete(timer(i));
                end
            end
        end
        
        
        function result = testIfOpen(app)
            result = false;
            handles = findall(groot, 'Type', 'figure');
            for h = handles'
                if strcmp(h.Name,app.MQTTGimblInterfaceUIFigure.Name) && h~=app.MQTTGimblInterfaceUIFigure
                    close(app.MQTTGimblInterfaceUIFigure);
                    warning('MQTT Gimbl Interface already open - shutting down.');
                    result = true;
                    return
                end
            end
            
        end
        
        function connectMQTT(app)
            try
                app.mqttBroker = mqtt(sprintf('tcp://%s',app.InitGetPref('mqttGimblInterface','BrokerIP','127.0.0.1')));
            catch
                answer = inputdlg('What is the mqtt broker ip?');
                setpref('mqttGimblInterface','BrokerIP',answer{1});
                app.mqttBroker = mqtt(sprintf('tcp://%s',answer{1}));
            end
            app.rewardSub = subscribe(app.mqttBroker,app.rewardTopic);
            app.idleSub = subscribe(app.mqttBroker,app.idleTopic);
            app.lickSub = subscribe(app.mqttBroker,app.lickTopic);
            app.rewardSub.Callback = @app.rewardMsg;
            app.idleSub.Callback = @app.idleCallback;
            app.lickSub.Callback = @app.lickCallback;    
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off',"MATLAB:class:PropUsingAtSyntax"); % syntax use of mqtt library (will become an error future release!)
            warning('off',"MATLAB:pfileOlderThanMfile");

            % check if alreayd open.
            if(app.testIfOpen()), return; end
            % preference amount.
            app.RewardulEditField.Value = app.InitGetPref('mqttGimblInterface','RewardAmount',app.RewardulEditField.Value);
            app.MaxRewardulEditField.Value = app.InitGetPref('mqttGimblInterface','MaxReward',app.MaxRewardulEditField.Value);
            app.ForcesStopCheckBox.Value = app.InitGetPref('mqttGimblInterface','ForceStop',app.ForcesStopCheckBox.Value );

            % Preference camera settings.
            % Camera 1.
            app.FrameRatehzEditField.Value = app.InitGetPref('mqttGimblInterface','Camera1FrameRate', app.FrameRatehzEditField.Value);
            app.TriggeronStartCheckBox.Value = app.InitGetPref('mqttGimblInterface','Camera1TriggerEnable', app.TriggeronStartCheckBox.Value);
            % Camera 2.
            app.FrameRatehzEditField_2.Value = app.InitGetPref('mqttGimblInterface','Camera2FrameRate', app.FrameRatehzEditField_2.Value);
            app.TriggeronStartCheckBox_2.Value = app.InitGetPref('mqttGimblInterface','Camera2TriggerEnable', app.TriggeronStartCheckBox_2.Value);
            % Camera 3.
            app.FrameRatehzEditField_3.Value = app.InitGetPref('mqttGimblInterface','Camera3FrameRate', app.FrameRatehzEditField_3.Value);
            app.TriggeronStartCheckBox_3.Value = app.InitGetPref('mqttGimblInterface','Camera3TriggerEnable', app.TriggeronStartCheckBox_3.Value);
            
            % Sound preference settings.
            app.EnableCheckBox.Value = app.InitGetPref('mqttGimblInterface','PCSoundEnable', app.EnableCheckBox.Value);
            app.FrequencyEditField.Value = app.InitGetPref('mqttGimblInterface','PCSoundFreq', app.FrequencyEditField.Value);
            app.DurationsecEditField.Value = app.InitGetPref('mqttGimblInterface','PCSoundDuration', app.DurationsecEditField.Value);
            app.genSoundData();
            
            % Preference Idle noise.
            app.EnableCheckBox_2.Value = app.InitGetPref('mqttGimblInterface','IdleSoundEnable', app.EnableCheckBox_2.Value);
            app.SoundtypeDropDown.Value = app.SoundtypeDropDown.Items{app.InitGetPref('mqttGimblInterface','IdleSoundType', 1)};
            app.DurationsecEditField_2.Value = app.InitGetPref('mqttGimblInterface','IdleSoundDuration', app.DurationsecEditField_2.Value);
            
            % Preferences auto-reward.
            app.IntervalEditField.Value = app.InitGetPref('mqttGimblInterface','IntervalAutoReward', app.IntervalEditField.Value);
            app.VariabilityEditField.Value = app.InitGetPref('mqttGimblInterface','VariabilityAutoReward', app.VariabilityEditField.Value);
            
            % Preferences folders.
            app.GimblLogEditField.Value = app.InitGetPref('mqttGimblInterface','GimblFolder', app.GimblLogEditField.Value);
            app.BonsaiVideoEditField.Value = app.InitGetPref('mqttGimblInterface','BonsaiFolder', app.BonsaiVideoEditField.Value);
            app.ScanImageEditField.Value = app.InitGetPref('mqttGimblInterface','ScanImageFolder', app.ScanImageEditField.Value);
            app.CompleteEditField.Value = app.InitGetPref('mqttGimblInterface','CompleteFolder', app.CompleteEditField.Value);
            app.CompleteScanImageEditField.Value = app.InitGetPref('mqttGimblInterface','CompleteScanImageFolder', app.CompleteScanImageEditField.Value);
            
            % Connect to MQTT.
            app.connectMQTT();
            
            %% Plot event indicators.
            app.hReward = scatter(app.UIAxes,datetime,NaN,'filled','MarkerEdgeColor','blue');
            hold(app.UIAxes,'on');
            app.hLick = scatter(app.UIAxes,datetime,NaN,'filled','MarkerEdgeColor','black',...
                'Marker','^');
            % Wait for graph update.
            addlistener(app.hReward,'XData','PostSet',@app.graphLimsUpdate);
            addlistener(app.hLick,'XData','PostSet',@app.graphLimsUpdate);
            
            % Load Calibration values.
            if (ispref('mqttGimblInterface','CalibrationValues'))
                app.calibrationValues = getpref('mqttGimblInterface','CalibrationValues');
            else
                app.calibrationValues = {0,0};
                addpref('mqttGimblInterface','CalibrationValues',app.calibrationValues);
            end
            app.UITable.Data = app.calibrationValues;
            % try calibration.
            app.calibrateCurve();
            
            % close excisting auto reward timers in memory.
            app.closeTimers();
            
            % Create session object.
            app.session = SessionFncs(app);
            app.external = ExternalFncs(app);
        end

        % Callback function
        function SerialPortDropDownValueChanged(app, event)
            value = app.SerialPortDropDown.Value;
            setpref('mqttGimblInterface','ComPort',value);
        end

        % Close request function: MQTTGimblInterfaceUIFigure
        function MQTTGimblInterfaceUIFigureCloseRequest(app, event)
            app.closeTimers();
            delete(app)
        end

        % Value changed function: RewardulEditField
        function RewardulEditFieldValueChanged(app, event)
            value = app.RewardulEditField.Value;
            setpref('mqttGimblInterface','RewardAmount',value);
        end

        % Button pushed function: RewardButton
        function RewardButtonPushed(app, event)
            app.waterReward(app.RewardulEditField.Value,'manual');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.connectMQTT(); % reset mqtt connection.
            app.external.SubscribeMqtt();
            app.clearPlot();
            app.TotalWaterulEditField.Value = 0;
            app.TotalRewardsnEditField.Value = 0;
        end

        % Button pushed function: PerformCalibrationButton
        function PerformCalibrationButtonPushed(app, event)
            for i=1:app.RepeatEditField.Value
                pause(1);
                publish(app.mqttBroker, 'miniBCS/Valve1/Pulse', num2str(app.DurationEditField.Value));
                app.CounterEditField.Value=i;
            end
            app.CounterEditField.Value=0;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.calibrationValues(indices(1),indices(2)) = {newData};
            setpref('mqttGimblInterface','CalibrationValues',app.calibrationValues);
            app.calibrationValues = getpref('mqttGimblInterface','CalibrationValues');
        end

        % Button pushed function: AddMeasurementButton
        function AddMeasurementButtonPushed(app, event)
            app.calibrationValues(end+1,:) = {0,0};
            app.UITable.Data = app.calibrationValues;
            setpref('mqttGimblInterface','CalibrationValues',app.calibrationValues);
        end

        % Button pushed function: RemoveMeasurementButton
        function RemoveMeasurementButtonPushed(app, event)
            if (~isempty(app.selectedCalCell))
                app.calibrationValues(app.selectedCalCell(1),:) = [];
                app.UITable.Data = app.calibrationValues;
                setpref('mqttGimblInterface','CalibrationValues',app.calibrationValues);
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selectedCalCell = indices;
        end

        % Button pushed function: CalculateCalibrationCurveButton
        function CalculateCalibrationCurveButtonPushed(app, event)
            if (length(app.calibrationValues)>=4)
                [xData,yData] = app.calibrateCurve();
                %plot.
                hFig = figure();
                hAx=axes();
                scatter(xData,yData,'filled'); hold on;
                plot(app.calibrationCurve);
                hAx.TickDir = 'out';
                legend off;
                hAx.XLabel.String = app.UITable.ColumnName{2};
                hAx.YLabel.String = app.UITable.ColumnName{1};
                hAx.XLim(1) = 0;hAx.YLim(1) = 0;
            else
                errordlg("Please add atleast 4 calibration measurements");
            end
        end

        % Value changed function: RangeminsEditField
        function RangeminsEditFieldValueChanged(app, event)
            value = app.RangeminsEditField.Value;
            graphLimsUpdate(app, [], []);
        end

        % Value changed function: FrameRatehzEditField
        function FrameRatehzEditFieldValueChanged(app, event)
            value = app.FrameRatehzEditField.Value;
            setpref('mqttGimblInterface','Camera1FrameRate',value);
        end

        % Value changed function: TriggeronStartCheckBox
        function TriggeronStartCheckBoxValueChanged(app, event)
            value = app.TriggeronStartCheckBox.Value;
            setpref('mqttGimblInterface','Camera1TriggerEnable',value);
        end

        % Value changed function: ForcesStopCheckBox
        function ForcesStopCheckBoxValueChanged(app, event)
            value = app.ForcesStopCheckBox.Value;
            setpref('mqttGimblInterface','ForceStop',value);
        end

        % Value changed function: MaxRewardulEditField
        function MaxRewardulEditFieldValueChanged(app, event)
            value = app.MaxRewardulEditField.Value;
            setpref('mqttGimblInterface','MaxReward',value);
        end

        % Value changed function: OpenValveButton
        function OpenValveButtonValueChanged(app, event)
            value = app.OpenValveButton.Value;
            if (value)
                publish(app.mqttBroker, 'miniBCS/Valve1/Open', '');
                app.OpenValveButton.Text = "Close Valve";
            else
                publish(app.mqttBroker, 'miniBCS/Valve1/Close', '');
                app.OpenValveButton.Text = "Open Valve";
            end           
        end

        % Value changed function: FrameRatehzEditField_2
        function FrameRatehzEditField_2ValueChanged(app, event)
            value = app.FrameRatehzEditField_2.Value;
            setpref('mqttGimblInterface','Camera2FrameRate',value);
        end

        % Value changed function: TriggeronStartCheckBox_2
        function TriggeronStartCheckBox_2ValueChanged(app, event)
            value = app.TriggeronStartCheckBox_2.Value;
            setpref('mqttGimblInterface','Camera2TriggerEnable',value);
        end

        % Value changed function: FrameRatehzEditField_3
        function FrameRatehzEditField_3ValueChanged(app, event)
            value = app.FrameRatehzEditField_3.Value;
            setpref('mqttGimblInterface','Camera3FrameRate',value);
        end

        % Value changed function: TriggeronStartCheckBox_3
        function TriggeronStartCheckBox_3ValueChanged(app, event)
            value = app.TriggeronStartCheckBox_3.Value;
            setpref('mqttGimblInterface','Camera3TriggerEnable',value);
        end

        % Value changed function: EnableCheckBox
        function EnableCheckBoxValueChanged(app, event)
            value = app.EnableCheckBox.Value;
            setpref('mqttGimblInterface','PCSoundEnable',value);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            setpref('mqttGimblInterface','PCSoundFreq',value);
            app.genSoundData();
        end

        % Value changed function: DurationsecEditField
        function DurationsecEditFieldValueChanged(app, event)
            value = app.DurationsecEditField.Value;
            setpref('mqttGimblInterface','PCSoundDuration',value);
            app.genSoundData();
        end

        % Value changed function: EnableCheckBox_2
        function EnableCheckBox_2ValueChanged(app, event)
            value = app.EnableCheckBox_2.Value;
            setpref('mqttGimblInterface','IdleSoundEnable',value);
        end

        % Value changed function: SoundtypeDropDown
        function SoundtypeDropDownValueChanged(app, event)
            value = app.SoundtypeDropDown.Value;
            setpref('mqttGimblInterface','IdleSoundType',value);
        end

        % Value changed function: DurationsecEditField_2
        function DurationsecEditField_2ValueChanged(app, event)
            value = app.DurationsecEditField_2.Value;
            setpref('mqttGimblInterface','IdleSoundDuration',value);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.idleSound("manual");
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            sound(app.tone,44100);
            publish(app.mqttBroker, 'Log/', sprintf('{"source":"matlab","msg":{"action":"Manual Reward Sound","frequency": %.2f,"duration":%.2f}}',app.FrequencyEditField.Value,app.DurationsecEditField.Value));
        end

        % Value changed function: AutoRewardButton
        function AutoRewardButtonValueChanged(app, event)
            value = app.AutoRewardButton.Value;
            if (value)
                app.startAutoTimer();
            end
        end

        % Value changed function: IntervalEditField
        function IntervalEditFieldValueChanged(app, event)
            value = app.IntervalEditField.Value;
            setpref('mqttGimblInterface','IntervalAutoReward',value);
        end

        % Value changed function: VariabilityEditField
        function VariabilityEditFieldValueChanged(app, event)
            value = app.VariabilityEditField.Value;
            setpref('mqttGimblInterface','VariabilityAutoReward',value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Camera1/Start', num2str(app.FrameRatehzEditField.Value))
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Camera1/Stop', "")
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Camera2/Start', num2str(app.FrameRatehzEditField_2.Value))
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Camera2/Stop', "")
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Camera3/Start', num2str(app.FrameRatehzEditField_3.Value))
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Camera3/Stop', "")
        end

        % Button pushed function: OnButtonLeft
        function OnButtonLeftPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Pusher/L/On', "")
        end

        % Button pushed function: OffButtonLeft
        function OffButtonLeftPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Pusher/L/Off', "")
        end

        % Button pushed function: OnButtonRight
        function OnButtonRightPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Pusher/R/On', "")
        end

        % Button pushed function: OffButtonRight
        function OffButtonRightPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Pusher/R/Off', "")
        end

        % Button pushed function: OnButtonFront
        function OnButtonFrontPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Pusher/Front/On', "")
        end

        % Button pushed function: OffButtonFront
        function OffButtonFrontPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Pusher/Front/Off', "")
        end

        % Button pushed function: OnButtonAll
        function OnButtonAllPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Pusher/All/On', "")
        end

        % Button pushed function: OffButtonAll
        function OffButtonAllPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Pusher/All/Off', "")
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Lock/On', "")
        end

        % Button pushed function: UnlockButton
        function UnlockButtonPushed(app, event)
            publish(app.mqttBroker, 'miniBCS/Lock/Off', "")
        end

        % Button pushed function: StartSessionButton
        function StartSessionButtonPushed(app, event)
            app.session.StartSession();
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            selpath = uigetdir(app.GimblLogEditField.Value,'Select output folder for Gimbl log');
            if ~isempty(selpath)
                app.GimblLogEditField.Value = selpath;
                setpref('mqttGimblInterface','GimblFolder',selpath);
            else
                
            end
        end

        % Value changed function: BonsaiVideoEditField
        function BonsaiVideoEditFieldValueChanged(app, event)
            value = app.BonsaiVideoEditField.Value;
            setpref('mqttGimblInterface','BonsaiFolder',value);
        end

        % Value changed function: GimblLogEditField
        function GimblLogEditFieldValueChanged(app, event)
            value = app.GimblLogEditField.Value;
            setpref('mqttGimblInterface','GimblFolder',value);
            
        end

        % Value changed function: ScanImageEditField
        function ScanImageEditFieldValueChanged(app, event)
            value = app.ScanImageEditField.Value;
            setpref('mqttGimblInterface','ScanImageFolder',value);
        end

        % Value changed function: CompleteEditField
        function CompleteEditFieldValueChanged(app, event)
            value = app.CompleteEditField.Value;
            setpref('mqttGimblInterface','CompleteFolder',value);
        end

        % Button pushed function: StopSessionButton
        function StopSessionButtonPushed(app, event)
            app.session.StopSession();
        end

        % Button pushed function: TransferFilesButton
        function TransferFilesButtonPushed(app, event)
            app.session.TransferFiles();
        end

        % Value changed function: CompleteScanImageEditField
        function CompleteScanImageEditFieldValueChanged(app, event)
            value = app.CompleteScanImageEditField.Value;
            setpref('mqttGimblInterface','CompleteScanImageFolder',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MQTTGimblInterfaceUIFigure = uifigure('Visible', 'off');
            app.MQTTGimblInterfaceUIFigure.Name = 'MQTT Gimbl Interface';
            app.MQTTGimblInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @MQTTGimblInterfaceUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MQTTGimblInterfaceUIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.UIAxes = uiaxes(app.ControlTab);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, {''; ''})
            ylabel(app.UIAxes, {''; ''})
            app.UIAxes.YLim = [0.5 2.5];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [1 2];
            app.UIAxes.YTickLabel = {'Lick'; 'Reward'};
            app.WaterRewardPanel = uipanel(app.ControlTab);
            app.WaterRewardPanel.Title = 'Water Reward';
            app.RewardButton = uibutton(app.WaterRewardPanel, 'push');
            app.RewardButton.ButtonPushedFcn = createCallbackFcn(app, @RewardButtonPushed, true);
            app.RewardButton.Text = 'Reward';
            app.RewardulEditFieldLabel = uilabel(app.WaterRewardPanel);
            app.RewardulEditFieldLabel.Text = 'Reward (ul)';
            app.RewardulEditField = uieditfield(app.WaterRewardPanel, 'numeric');
            app.RewardulEditField.Limits = [0 Inf];
            app.RewardulEditField.ValueChangedFcn = createCallbackFcn(app, @RewardulEditFieldValueChanged, true);
            app.RewardulEditField.Value = 5;
            app.OpenValveButton = uibutton(app.WaterRewardPanel, 'state');
            app.OpenValveButton.ValueChangedFcn = createCallbackFcn(app, @OpenValveButtonValueChanged, true);
            app.OpenValveButton.Text = 'Open Valve';
            app.MaxRewardulEditFieldLabel = uilabel(app.WaterRewardPanel);
            app.MaxRewardulEditFieldLabel.Text = 'Max Reward (ul)';
            app.MaxRewardulEditField = uieditfield(app.WaterRewardPanel, 'numeric');
            app.MaxRewardulEditField.Limits = [0 Inf];
            app.MaxRewardulEditField.ValueChangedFcn = createCallbackFcn(app, @MaxRewardulEditFieldValueChanged, true);
            app.MaxRewardulEditField.Value = 1400;
            app.ForcesStopCheckBox = uicheckbox(app.WaterRewardPanel);
            app.ForcesStopCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcesStopCheckBoxValueChanged, true);
            app.ForcesStopCheckBox.Text = 'Forces Stop';
            app.EveryEditFieldLabel = uilabel(app.WaterRewardPanel);
            app.EveryEditFieldLabel.Text = 'Every';
            app.IntervalEditField = uieditfield(app.WaterRewardPanel, 'numeric');
            app.IntervalEditField.Limits = [1 Inf];
            app.IntervalEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalEditFieldValueChanged, true);
            app.IntervalEditField.Value = 5;
            app.Label = uilabel(app.WaterRewardPanel);
            app.Label.Text = '+-';
            app.VariabilityEditField = uieditfield(app.WaterRewardPanel, 'numeric');
            app.VariabilityEditField.Limits = [0 Inf];
            app.VariabilityEditField.ValueChangedFcn = createCallbackFcn(app, @VariabilityEditFieldValueChanged, true);
            app.VariabilityEditField.Value = 1;
            app.secondsLabel = uilabel(app.WaterRewardPanel);
            app.secondsLabel.Text = 'seconds';
            app.AutoRewardButton = uibutton(app.WaterRewardPanel, 'state');
            app.AutoRewardButton.ValueChangedFcn = createCallbackFcn(app, @AutoRewardButtonValueChanged, true);
            app.AutoRewardButton.Text = 'Auto Reward';
            app.ResetButton = uibutton(app.ControlTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TotalWaterulEditFieldLabel = uilabel(app.ControlTab);
            app.TotalWaterulEditFieldLabel.Text = 'Total Water (ul)';
            app.TotalWaterulEditField = uieditfield(app.ControlTab, 'numeric');
            app.TotalWaterulEditField.Editable = 'off';
            app.TotalRewardsnEditFieldLabel = uilabel(app.ControlTab);
            app.TotalRewardsnEditFieldLabel.Text = 'Total Rewards (n)';
            app.TotalRewardsnEditField = uieditfield(app.ControlTab, 'numeric');
            app.TotalRewardsnEditField.Editable = 'off';
            app.RangeminsEditFieldLabel = uilabel(app.ControlTab);
            app.RangeminsEditFieldLabel.Text = 'Range (mins)';
            app.RangeminsEditField = uieditfield(app.ControlTab, 'numeric');
            app.RangeminsEditField.Limits = [1 Inf];
            app.RangeminsEditField.ValueChangedFcn = createCallbackFcn(app, @RangeminsEditFieldValueChanged, true);
            app.RangeminsEditField.Value = 5;
            app.SessionTab = uitab(app.TabGroup);
            app.SessionTab.Title = 'Session';
            app.FileFolderLocationsPanel = uipanel(app.SessionTab);
            app.FileFolderLocationsPanel.Title = 'File Folder Locations';
            app.GimblLogEditFieldLabel = uilabel(app.FileFolderLocationsPanel);
            app.GimblLogEditFieldLabel.Text = 'Gimbl Log';
            app.GimblLogEditField = uieditfield(app.FileFolderLocationsPanel, 'text');
            app.GimblLogEditField.ValueChangedFcn = createCallbackFcn(app, @GimblLogEditFieldValueChanged, true);
            app.SelectButton = uibutton(app.FileFolderLocationsPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.BonsaiVideoEditFieldLabel = uilabel(app.FileFolderLocationsPanel);
            app.BonsaiVideoEditFieldLabel.Text = 'Bonsai Video';
            app.BonsaiVideoEditField = uieditfield(app.FileFolderLocationsPanel, 'text');
            app.BonsaiVideoEditField.ValueChangedFcn = createCallbackFcn(app, @BonsaiVideoEditFieldValueChanged, true);
            app.ScanImageEditFieldLabel = uilabel(app.FileFolderLocationsPanel);
            app.ScanImageEditFieldLabel.Text = 'ScanImage';
            app.ScanImageEditField = uieditfield(app.FileFolderLocationsPanel, 'text');
            app.ScanImageEditField.ValueChangedFcn = createCallbackFcn(app, @ScanImageEditFieldValueChanged, true);
            app.NoteFolderlocationsneedtobeaccessiblebylocalcomputersLabel = uilabel(app.FileFolderLocationsPanel);
            app.NoteFolderlocationsneedtobeaccessiblebylocalcomputersLabel.Text = 'Note: Folder locations need to be accessible by local computers';
            app.CompleteEditFieldLabel = uilabel(app.FileFolderLocationsPanel);
            app.CompleteEditFieldLabel.Text = 'Complete';
            app.CompleteEditField = uieditfield(app.FileFolderLocationsPanel, 'text');
            app.CompleteEditField.ValueChangedFcn = createCallbackFcn(app, @CompleteEditFieldValueChanged, true);
            app.CompleteScanImageEditFieldLabel = uilabel(app.FileFolderLocationsPanel);
            app.CompleteScanImageEditFieldLabel.Text = {'Complete'; 'ScanImage'};
            app.CompleteScanImageEditField = uieditfield(app.FileFolderLocationsPanel, 'text');
            app.CompleteScanImageEditField.ValueChangedFcn = createCallbackFcn(app, @CompleteScanImageEditFieldValueChanged, true);
            app.StartSessionButton = uibutton(app.SessionTab, 'push');
            app.StartSessionButton.ButtonPushedFcn = createCallbackFcn(app, @StartSessionButtonPushed, true);
            app.StartSessionButton.Text = 'Start Session';
            app.ResponsesPanel = uipanel(app.SessionTab);
            app.ResponsesPanel.Title = 'Responses';
            app.GimblLampLabel = uilabel(app.ResponsesPanel);
            app.GimblLampLabel.Text = 'Gimbl';
            app.GimblLamp = uilamp(app.ResponsesPanel);
            app.BonsaiLampLabel = uilabel(app.ResponsesPanel);
            app.BonsaiLampLabel.Text = 'Bonsai';
            app.BonsaiLamp = uilamp(app.ResponsesPanel);
            app.ScanImageLampLabel = uilabel(app.ResponsesPanel);
            app.ScanImageLampLabel.Text = 'ScanImage';
            app.ScanImageLamp = uilamp(app.ResponsesPanel);
            app.CurrentLabel = uilabel(app.ResponsesPanel);
            app.CurrentLabel.Text = 'Current:';
            app.StopSessionButton = uibutton(app.SessionTab, 'push');
            app.StopSessionButton.ButtonPushedFcn = createCallbackFcn(app, @StopSessionButtonPushed, true);
            app.StopSessionButton.Text = 'Stop Session';
            app.TransferFilesButton = uibutton(app.SessionTab, 'push');
            app.TransferFilesButton.ButtonPushedFcn = createCallbackFcn(app, @TransferFilesButtonPushed, true);
            app.TransferFilesButton.Text = 'Transfer Files';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationPanel = uipanel(app.CalibrationTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.RepeatEditFieldLabel = uilabel(app.CalibrationPanel);
            app.RepeatEditFieldLabel.Text = 'Repeat';
            app.RepeatEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.RepeatEditField.Value = 100;
            app.PerformCalibrationButton = uibutton(app.CalibrationPanel, 'push');
            app.PerformCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformCalibrationButtonPushed, true);
            app.PerformCalibrationButton.Text = 'Perform Calibration';
            app.DurationEditFieldLabel = uilabel(app.CalibrationPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.CounterEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CounterEditFieldLabel.Text = 'Counter';
            app.CounterEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.CounterEditField.Editable = 'off';
            app.CalibrationMeasurementsPanel = uipanel(app.CalibrationTab);
            app.CalibrationMeasurementsPanel.Title = 'Calibration Measurements';
            app.UITable = uitable(app.CalibrationMeasurementsPanel);
            app.UITable.ColumnName = {'Duration (ms)'; 'Avg. Volume (ul)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddMeasurementButton = uibutton(app.CalibrationMeasurementsPanel, 'push');
            app.AddMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @AddMeasurementButtonPushed, true);
            app.AddMeasurementButton.Text = 'Add Measurement';
            app.RemoveMeasurementButton = uibutton(app.CalibrationMeasurementsPanel, 'push');
            app.RemoveMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMeasurementButtonPushed, true);
            app.RemoveMeasurementButton.Text = 'Remove Measurement';
            app.CalculateCalibrationCurveButton = uibutton(app.CalibrationMeasurementsPanel, 'push');
            app.CalculateCalibrationCurveButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCalibrationCurveButtonPushed, true);
            app.CalculateCalibrationCurveButton.Text = 'Calculate Calibration Curve';
            app.CamerasTab = uitab(app.TabGroup);
            app.CamerasTab.Title = 'Cameras';
            app.Camera1SettingsPanel = uipanel(app.CamerasTab);
            app.Camera1SettingsPanel.Title = 'Camera 1 Settings';
            app.TriggeronStartCheckBox = uicheckbox(app.Camera1SettingsPanel);
            app.TriggeronStartCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriggeronStartCheckBoxValueChanged, true);
            app.TriggeronStartCheckBox.Text = 'Trigger on Start';
            app.FrameRatehzEditFieldLabel = uilabel(app.Camera1SettingsPanel);
            app.FrameRatehzEditFieldLabel.Text = 'Frame Rate (hz)';
            app.FrameRatehzEditField = uieditfield(app.Camera1SettingsPanel, 'numeric');
            app.FrameRatehzEditField.Limits = [1 250];
            app.FrameRatehzEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRatehzEditFieldValueChanged, true);
            app.FrameRatehzEditField.Value = 50;
            app.StartButton = uibutton(app.Camera1SettingsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Camera1SettingsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Camera2SettingsPanel = uipanel(app.CamerasTab);
            app.Camera2SettingsPanel.Title = 'Camera 2 Settings';
            app.TriggeronStartCheckBox_2 = uicheckbox(app.Camera2SettingsPanel);
            app.TriggeronStartCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @TriggeronStartCheckBox_2ValueChanged, true);
            app.TriggeronStartCheckBox_2.Text = 'Trigger on Start';
            app.FrameRatehzEditField_2Label = uilabel(app.Camera2SettingsPanel);
            app.FrameRatehzEditField_2Label.Text = 'Frame Rate (hz)';
            app.FrameRatehzEditField_2 = uieditfield(app.Camera2SettingsPanel, 'numeric');
            app.FrameRatehzEditField_2.Limits = [1 250];
            app.FrameRatehzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrameRatehzEditField_2ValueChanged, true);
            app.FrameRatehzEditField_2.Value = 50;
            app.StartButton_2 = uibutton(app.Camera2SettingsPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.Camera2SettingsPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.Camera3SettingsPanel = uipanel(app.CamerasTab);
            app.Camera3SettingsPanel.Title = 'Camera 3 Settings';
            app.TriggeronStartCheckBox_3 = uicheckbox(app.Camera3SettingsPanel);
            app.TriggeronStartCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @TriggeronStartCheckBox_3ValueChanged, true);
            app.TriggeronStartCheckBox_3.Text = 'Trigger on Start';
            app.FrameRatehzEditField_3Label = uilabel(app.Camera3SettingsPanel);
            app.FrameRatehzEditField_3Label.Text = 'Frame Rate (hz)';
            app.FrameRatehzEditField_3 = uieditfield(app.Camera3SettingsPanel, 'numeric');
            app.FrameRatehzEditField_3.Limits = [1 250];
            app.FrameRatehzEditField_3.ValueChangedFcn = createCallbackFcn(app, @FrameRatehzEditField_3ValueChanged, true);
            app.FrameRatehzEditField_3.Value = 50;
            app.StartButton_3 = uibutton(app.Camera3SettingsPanel, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start';
            app.StopButton_3 = uibutton(app.Camera3SettingsPanel, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Text = 'Stop';
            app.SoundTab = uitab(app.TabGroup);
            app.SoundTab.Title = 'Sound';
            app.RewardSoundPanel = uipanel(app.SoundTab);
            app.RewardSoundPanel.Title = 'Reward Sound';
            app.EnableCheckBox = uicheckbox(app.RewardSoundPanel);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
            app.FrequencyEditFieldLabel = uilabel(app.RewardSoundPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.RewardSoundPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 8000;
            app.DurationsecEditFieldLabel = uilabel(app.RewardSoundPanel);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.RewardSoundPanel, 'numeric');
            app.DurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanged, true);
            app.DurationsecEditField.Value = 0.5;
            app.PlayButton_2 = uibutton(app.RewardSoundPanel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.IdleNoisePanel = uipanel(app.SoundTab);
            app.IdleNoisePanel.Title = 'Idle Noise';
            app.SoundtypeDropDownLabel = uilabel(app.IdleNoisePanel);
            app.SoundtypeDropDownLabel.Text = 'Sound type';
            app.SoundtypeDropDown = uidropdown(app.IdleNoisePanel);
            app.SoundtypeDropDown.Items = {'White Noise'};
            app.SoundtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SoundtypeDropDownValueChanged, true);
            app.SoundtypeDropDown.Value = 'White Noise';
            app.EnableCheckBox_2 = uicheckbox(app.IdleNoisePanel);
            app.EnableCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBox_2ValueChanged, true);
            app.EnableCheckBox_2.Text = 'Enable';
            app.EnableCheckBox_2.Value = true;
            app.DurationsecEditField_2Label = uilabel(app.IdleNoisePanel);
            app.DurationsecEditField_2Label.Text = 'Duration (sec)';
            app.DurationsecEditField_2 = uieditfield(app.IdleNoisePanel, 'numeric');
            app.DurationsecEditField_2.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditField_2ValueChanged, true);
            app.DurationsecEditField_2.Value = 1;
            app.PlayButton = uibutton(app.IdleNoisePanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.BallTab = uitab(app.TabGroup);
            app.BallTab.Title = 'Ball';
            app.LockBallServoControlPanel = uipanel(app.BallTab);
            app.LockBallServoControlPanel.Title = 'Lock Ball Servo Control';
            app.LockButton = uibutton(app.LockBallServoControlPanel, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock';
            app.UnlockButton = uibutton(app.LockBallServoControlPanel, 'push');
            app.UnlockButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockButtonPushed, true);
            app.UnlockButton.Text = 'Unlock';
            app.AirPushersPanel = uipanel(app.BallTab);
            app.AirPushersPanel.Title = 'Air Pushers';
            app.LeftPanel = uipanel(app.AirPushersPanel);
            app.LeftPanel.Title = 'Left';
            app.OnButtonLeft = uibutton(app.LeftPanel, 'push');
            app.OnButtonLeft.ButtonPushedFcn = createCallbackFcn(app, @OnButtonLeftPushed, true);
            app.OnButtonLeft.Text = 'On';
            app.OffButtonLeft = uibutton(app.LeftPanel, 'push');
            app.OffButtonLeft.ButtonPushedFcn = createCallbackFcn(app, @OffButtonLeftPushed, true);
            app.OffButtonLeft.Text = 'Off';
            app.RightPanel = uipanel(app.AirPushersPanel);
            app.RightPanel.Title = 'Right';
            app.OnButtonRight = uibutton(app.RightPanel, 'push');
            app.OnButtonRight.ButtonPushedFcn = createCallbackFcn(app, @OnButtonRightPushed, true);
            app.OnButtonRight.Text = 'On';
            app.OffButtonRight = uibutton(app.RightPanel, 'push');
            app.OffButtonRight.ButtonPushedFcn = createCallbackFcn(app, @OffButtonRightPushed, true);
            app.OffButtonRight.Text = 'Off';
            app.FrontPanel = uipanel(app.AirPushersPanel);
            app.FrontPanel.Title = 'Front';
            app.OnButtonFront = uibutton(app.FrontPanel, 'push');
            app.OnButtonFront.ButtonPushedFcn = createCallbackFcn(app, @OnButtonFrontPushed, true);
            app.OnButtonFront.Text = 'On';
            app.OffButtonFront = uibutton(app.FrontPanel, 'push');
            app.OffButtonFront.ButtonPushedFcn = createCallbackFcn(app, @OffButtonFrontPushed, true);
            app.OffButtonFront.Text = 'Off';
            app.AllPanel = uipanel(app.AirPushersPanel);
            app.AllPanel.Title = 'All';
            app.OnButtonAll = uibutton(app.AllPanel, 'push');
            app.OnButtonAll.ButtonPushedFcn = createCallbackFcn(app, @OnButtonAllPushed, true);
            app.OnButtonAll.Text = 'On';
            app.OffButtonAll = uibutton(app.AllPanel, 'push');
            app.OffButtonAll.ButtonPushedFcn = createCallbackFcn(app, @OffButtonAllPushed, true);
            app.OffButtonAll.Text = 'Off';
            % Show the figure after all components are created
            app.MQTTGimblInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MQTTGimblInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MQTTGimblInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MQTTGimblInterfaceUIFigure)
        end
    end
end